classdef ExamenBrazo_Ainhoa_Legaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RESULTADOSTextArea              matlab.ui.control.TextArea
        RESULTADOSTextAreaLabel         matlab.ui.control.Label
        SIMULARButton                   matlab.ui.control.Button
        SELECCIONEUNAOPCINPanel         matlab.ui.container.Panel
        GeomtricoCheckBox               matlab.ui.control.CheckBox
        HomogneaCheckBox                matlab.ui.control.CheckBox
        LONGITUDESDELASARTICULACIONESENMETROSPanel  matlab.ui.container.Panel
        Longitudeslabn1mEditField       matlab.ui.control.NumericEditField
        Longitudeslabn1mEditFieldLabel  matlab.ui.control.Label
        Longitudeslabn2mEditField       matlab.ui.control.NumericEditField
        Longitudeslabn2mEditFieldLabel  matlab.ui.control.Label
        Longitudeslabn3mEditField       matlab.ui.control.NumericEditField
        Longitudeslabn3mEditFieldLabel  matlab.ui.control.Label
        NGULODELASARTICULACIONESENGRADOSPanel  matlab.ui.container.Panel
        nguloarticulacin4EditField      matlab.ui.control.NumericEditField
        nguloarticulacin4EditFieldLabel  matlab.ui.control.Label
        nguloarticulacin3EditField      matlab.ui.control.NumericEditField
        nguloarticulacin3EditFieldLabel  matlab.ui.control.Label
        nguloarticulacin2EditField      matlab.ui.control.NumericEditField
        nguloarticulacin2EditFieldLabel  matlab.ui.control.Label
        nguloarticulacin1EditField      matlab.ui.control.NumericEditField
        nguloarticulacin1EditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: GeomtricoCheckBox
        function GeomtricoCheckBoxValueChanged(app, event)
            if app.GeomtricoCheckBox.Value
                app.HomogneaCheckBox.Value = false;
            else
                app.GeomtricoCheckBox.Value = true;       
            end            
        end

        % Value changed function: HomogneaCheckBox
        function HomogneaCheckBoxValueChanged(app, event)
            if app.HomogneaCheckBox.Value
                app.GeomtricoCheckBox.Value = false;
            else
                app.HomogneaCheckBox.Value = true;
            end
        end

        % Button pushed function: SIMULARButton
        function SIMULARButtonPushed(app, event)
            % Longitudes de los eslabones
            l = [app.Longitudeslabn1mEditField.Value, ...
                app.Longitudeslabn2mEditField.Value, ...
                app.Longitudeslabn3mEditField.Value];

            % Si algun valor es negativo, se corta la simulación y se manda
            % una alerta
            if any(l <= 0)
                uialert(app.UIFigure, ['Las longitudes de los eslabones' ...
                    ' deben ser mayores que 0.'], 'Faltan datos');
            return
            end

            % Ángulos del brazo
            theta_grados = [app.nguloarticulacin1EditField.Value, ...
                    app.nguloarticulacin2EditField.Value, ...
                    app.nguloarticulacin3EditField.Value, ...
                    app.nguloarticulacin4EditField.Value];

            theta_radianes = deg2rad(theta_grados);

            % Si no selecciona una opción, se corta la simulación y se 
            % manda una alerta
            if ~app.GeomtricoCheckBox.Value && ~app.HomogneaCheckBox.Value
                uialert(app.UIFigure, ['Selecciona un método (Geométrico' ...
                    ' u Homogénea).'], 'Faltan datos');
            return
            end

            L = sum(l) * 1.2;

            for t = 0:0.02:1
                th = theta_radianes * t;
                if app.GeomtricoCheckBox.Value
                    [Base, Art1, Art2, Art3] = Geometrico(l(1), l(2), l(3), ...
                    th(1), th(2), th(3), th(4));
                else
                    [Base, Art1, Art2, Art3] = Homogenea(l(1), l(2), l(3), ...
                    th(1), th(2), th(3), th(4));
                end
               
                    cla(app.UIAxes)
                    DibujaEjes(app.UIAxes, L);
                    LineaPuntos(app.UIAxes, Base, Art1, 'black');
                    LineaPuntos(app.UIAxes, Art1, Art2, 'black');
                    LineaPuntos(app.UIAxes, Art2, Art3, 'black');
                    scatter3(app.UIAxes, [Base(1) Art1(1) Art2(1) Art3(1)], ...
                                [Base(2) Art1(2) Art2(2) Art3(2)], ...
                                [Base(3) Art1(3) Art2(3) Art3(3)], ...
                                80, 'filled', 'MarkerFaceColor', 'b');
                    pause(0.02)
            end

            if app.HomogneaCheckBox.Value
                % Formula Homogenea
                Formula_Homogenea = sprintf(['T = Rz(%.2f)*Ry(90°)*Rz(90°)*' ...
                    'Rz(%.2f)*Tx(%.2f)*Rz(%.2f)*Tx(%.2f)*Rz(%.2f)*Tx(%.2f)'], ...
                    theta_grados(1), theta_grados(2), l(1), ...
                    theta_grados(3), l(2), theta_grados(4), l(3));
                % Display
                app.RESULTADOSTextArea.Value = Formula_Homogenea;
            else
                % Formula_Geometrica            
                Formula_Geometrica = sprintf(['EF = [EFx = %.2f m, ' ...
                    'EFy = %.2f m, EFz = %.2f m]'], Art3(1), Art3(2), Art3(3));
                % Display
                app.RESULTADOSTextArea.Value = Formula_Geometrica;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BRAZO ROBÓTICO')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NGULODELASARTICULACIONESENGRADOSPanel = uipanel(app.UIFigure);
            app.NGULODELASARTICULACIONESENGRADOSPanel.Title = 'ÁNGULO DE LAS ARTICULACIONES EN GRADOS:';
            app.nguloarticulacin1EditFieldLabel = uilabel(app.NGULODELASARTICULACIONESENGRADOSPanel);
            app.nguloarticulacin1EditFieldLabel.Text = 'Ángulo articulación 1 [°]';
            app.nguloarticulacin1EditField = uieditfield(app.NGULODELASARTICULACIONESENGRADOSPanel, 'numeric');
            app.nguloarticulacin2EditFieldLabel = uilabel(app.NGULODELASARTICULACIONESENGRADOSPanel);
            app.nguloarticulacin2EditFieldLabel.Text = 'Ángulo articulación 2 [°]';
            app.nguloarticulacin2EditField = uieditfield(app.NGULODELASARTICULACIONESENGRADOSPanel, 'numeric');
            app.nguloarticulacin3EditFieldLabel = uilabel(app.NGULODELASARTICULACIONESENGRADOSPanel);
            app.nguloarticulacin3EditFieldLabel.Text = 'Ángulo articulación 3 [°]';
            app.nguloarticulacin3EditField = uieditfield(app.NGULODELASARTICULACIONESENGRADOSPanel, 'numeric');
            app.nguloarticulacin4EditFieldLabel = uilabel(app.NGULODELASARTICULACIONESENGRADOSPanel);
            app.nguloarticulacin4EditFieldLabel.Text = 'Ángulo articulación 4 [°]';
            app.nguloarticulacin4EditField = uieditfield(app.NGULODELASARTICULACIONESENGRADOSPanel, 'numeric');
            app.LONGITUDESDELASARTICULACIONESENMETROSPanel = uipanel(app.UIFigure);
            app.LONGITUDESDELASARTICULACIONESENMETROSPanel.Title = 'LONGITUDES DE LAS ARTICULACIONES EN METROS:';
            app.Longitudeslabn3mEditFieldLabel = uilabel(app.LONGITUDESDELASARTICULACIONESENMETROSPanel);
            app.Longitudeslabn3mEditFieldLabel.Text = 'Longitud eslabón 3 [m]';
            app.Longitudeslabn3mEditField = uieditfield(app.LONGITUDESDELASARTICULACIONESENMETROSPanel, 'numeric');
            app.Longitudeslabn2mEditFieldLabel = uilabel(app.LONGITUDESDELASARTICULACIONESENMETROSPanel);
            app.Longitudeslabn2mEditFieldLabel.Text = 'Longitud eslabón 2 [m]';
            app.Longitudeslabn2mEditField = uieditfield(app.LONGITUDESDELASARTICULACIONESENMETROSPanel, 'numeric');
            app.Longitudeslabn1mEditFieldLabel = uilabel(app.LONGITUDESDELASARTICULACIONESENMETROSPanel);
            app.Longitudeslabn1mEditFieldLabel.Text = 'Longitud eslabón 1 [m]';
            app.Longitudeslabn1mEditField = uieditfield(app.LONGITUDESDELASARTICULACIONESENMETROSPanel, 'numeric');
            app.SELECCIONEUNAOPCINPanel = uipanel(app.UIFigure);
            app.SELECCIONEUNAOPCINPanel.Title = 'SELECCIONE UNA OPCIÓN:';
            app.HomogneaCheckBox = uicheckbox(app.SELECCIONEUNAOPCINPanel);
            app.HomogneaCheckBox.ValueChangedFcn = createCallbackFcn(app, @HomogneaCheckBoxValueChanged, true);
            app.HomogneaCheckBox.Text = 'Homogénea';
            app.GeomtricoCheckBox = uicheckbox(app.SELECCIONEUNAOPCINPanel);
            app.GeomtricoCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeomtricoCheckBoxValueChanged, true);
            app.GeomtricoCheckBox.Text = 'Geométrico';
            app.SIMULARButton = uibutton(app.UIFigure, 'push');
            app.SIMULARButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.SIMULARButton.Text = 'SIMULAR';
            app.RESULTADOSTextAreaLabel = uilabel(app.UIFigure);
            app.RESULTADOSTextAreaLabel.Text = 'RESULTADOS';
            app.RESULTADOSTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExamenBrazo_Ainhoa_Legaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end